classdef ComePortV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COMCONUIFigure            matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        RefreshButton             matlab.ui.control.Button
        ComDropDownLabel          matlab.ui.control.Label
        ComPort                   matlab.ui.control.DropDown
        BaudRateEditFieldLabel    matlab.ui.control.Label
        BaudRate                  matlab.ui.control.NumericEditField
        ConnectButton             matlab.ui.control.Button
        DisconnectButton          matlab.ui.control.Button
        SaveSettingButton         matlab.ui.control.Button
        LoadSettingButton         matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        RecivedTextArea           matlab.ui.control.TextArea
        SendTextFiled             matlab.ui.control.EditField
        SendButton                matlab.ui.control.Button
        SendTerminate             matlab.ui.control.CheckBox
        RecivedTypeDropDownLabel  matlab.ui.control.Label
        RecivedType               matlab.ui.control.DropDown
        SaveButton                matlab.ui.control.Button
        RecivedClearButton        matlab.ui.control.Button
        SaveDef                   matlab.ui.control.Button
        PreDef1                   matlab.ui.control.Button
        ClearSendText             matlab.ui.control.Button
        PreDef2                   matlab.ui.control.Button
        PreDef3                   matlab.ui.control.Button
        PreDef4                   matlab.ui.control.Button
        PreDef5                   matlab.ui.control.Button
        PreDef6                   matlab.ui.control.Button
        PreDef7                   matlab.ui.control.Button
        PreDef8                   matlab.ui.control.Button
        PreDef9                   matlab.ui.control.Button
        PreDef10                  matlab.ui.control.Button
        PreDef11                  matlab.ui.control.Button
        PreDef12                  matlab.ui.control.Button
        PreDef13                  matlab.ui.control.Button
        PreDef14                  matlab.ui.control.Button
        PreDef15                  matlab.ui.control.Button
        Echo                      matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        PredefinesNumbers       =          15
        ObjectToVis=["PreDef2";
            "PreDef1"]
    end
    
    properties (Access = public)
        SerialPort % Serial Port that connect to the device
    end
    
    methods (Access = private)
        function ReciveData(app,SerialPort,~)
            Char=read(SerialPort,1,"char");
            if ~ismember(Char,[10,13])% \n =10 \r =13
                app.RecivedTextArea.Value{end}(end+1)=Char;
            elseif (Char == 10)
                app.RecivedTextArea.Value{end+1}='';
                scroll(app.RecivedTextArea,'bottom');
            end
        end
        
        function Value = ExtractSetting(~,String,Patern)
            Patern=[Patern,':['];
            i=strfind(String,Patern);
            i=i+length(Patern);
            j=strfind(String(i:end),']');
            j=j(1)-1;
            Value=String(i:i+j-1);
        end
    end
    
    methods (Access = private)
        function SendString(app,String,SendTerminate)
            if isempty(String)
                return;
            end
            if app.Echo.Value
                app.RecivedTextArea.Value{end}(end+1:end+length(String))=String;
                if SendTerminate
                    app.RecivedTextArea.Value{end+1}='';
                    scroll(app.RecivedTextArea,'bottom');
                end
            end
            write(app.SerialPort,String,"string")
            if SendTerminate
                write(app.SerialPort,10,"char")
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ComPort.Items=serialportlist("all");
            app.ComPort.Value=app.ComPort.Items{end};
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.COMCONUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {446, 446};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {196, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.ComPort.Items=serialportlist("all");
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            ConnectSucseesfully=true;
            %global Port;
            try
                if isa(app.SerialPort,'handle')
                    app.SerialPort.delete;
                end
                app.SerialPort=serialport(app.ComPort.Value,app.BaudRate.Value);
                configureCallback(app.SerialPort,"byte",1,@app.ReciveData);
            catch Error
                ConnectSucseesfully=false;
                errordlg({'Unable To Connect',Error.message},...
                    'Connection Error');
            end
            if ConnectSucseesfully
                app.ComPort.Enable="off";
                app.BaudRate.Enable="off";
                configureTerminator(app.SerialPort,"CR");
                app.DisconnectButton.Enable="on";
                app.RecivedTextArea.Enable="on";
                app.SendTextFiled.Enable="on";
                app.SendButton.Enable="on";
                app.RightPanel.Enable='on';
            end
        end

        % Close request function: COMCONUIFigure
        function COMCONUIFigureCloseRequest(app, event)
            if isa(app.SerialPort,'handle')
                app.SerialPort.delete;
            end
            delete(app)
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.SerialPort.delete;
            app.ComPort.Enable="on";
            app.BaudRate.Enable="on";
            app.DisconnectButton.Enable="off";
            app.RightPanel.Enable='off';
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.SendString(app.SendTextFiled.Value,app.SendTerminate.Value);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [File,Path]=uiputfile("*.txt",'Save Log');
            if ~(isequal(File,0) || isequal(Path,0))
                FileName=[Path,File];
                F=fopen(FileName,'W',"ieee-be",'UTF-8');
                for i=1:length(app.RecivedTextArea.Value)
                    fprintf(F,'%c',app.RecivedTextArea.Value{i});
                    fprintf(F,'\n');
                end
                fclose(F);
            end
        end

        % Button pushed function: RecivedClearButton
        function RecivedClearButtonPushed(app, event)
            app.RecivedTextArea.Value={''};
        end

        % Button pushed function: PreDef1, PreDef10, PreDef11, 
        % PreDef12, PreDef13, PreDef14, PreDef15, PreDef2, PreDef3, 
        % PreDef4, PreDef5, PreDef6, PreDef7, PreDef8, PreDef9
        function PreDefButtonsPushed(app, event)
            app.SendString(event.Source.Text,0);
        end

        % Button pushed function: SaveDef
        function SaveDefButtonPushed(app, event)
            Predef=cell(1,app.PredefinesNumbers);
            List=cell(1,app.PredefinesNumbers);
            for i=1:app.PredefinesNumbers
                Predef{i}=sprintf('PreDef%d',i);
                if isempty(app.(Predef{i}).Text)
                    Text='[empty]';
                else
                    Text=app.(Predef{i}).Text;
                    while ismember(Text(end),[10,13])
                        Text(end)=[];
                    end
                end
                List{i}=['Def ',num2str(i,'%02d: '),Text];
            end
            [Selected,UserSelec] = listdlg('PromptString','Select a Predefine index that You want to save on it.',...
                'SelectionMode','single','listsize',[160 220],'ListString',List);
            if UserSelec
                if ~isempty(app.(Predef{Selected}).Text)
                    Ans = questdlg("Replace it?",'',"Yes","No","No");
                    if (Ans~="Yes")
                        return;
                    end
                end
                app.(Predef{Selected}).Text=app.SendTextFiled.Value;
                if app.SendTerminate.Value
                    app.(Predef{Selected}).Text(end+1)=10;
                end
            end
        end

        % Button pushed function: ClearSendText
        function ClearSendTextButtonPushed(app, event)
            app.SendTextFiled.Value='';
        end

        % Button pushed function: SaveSettingButton
        function SaveSettingButtonPushed(app, event)
            [File,Path]=uiputfile("*.SCD",'Save Setting');
            if ~(isequal(File,0) || isequal(Path,0))
                FileName=[Path,File];
                F=fopen(FileName,'W');
                fprintf(F,'Comunication Setting File\nVersion202004\n\n\n\n\n\n\n');
                fprintf(F,'ComPort:[%s]\n',app.ComPort.Value);
                fprintf(F,'BaudRate:[%d]\n',app.BaudRate.Value);
                fprintf(F,'Echo:[%d]\n',app.Echo.Value);
                fprintf(F,'RecivedType:[%s]\n',app.RecivedType.Value);
                fprintf(F,'SendTerminate:[%d]\n',app.SendTerminate.Value);
                for i=1:app.PredefinesNumbers
                    Field=sprintf('PreDef%d',i);
                    fprintf(F,[Field,':[%s]\n'],app.(Field).Text);
                end
                fclose(F);
            end
        end

        % Button pushed function: LoadSettingButton
        function LoadSettingButtonPushed(app, event)
            [File,Path]=uigetfile("*.SCD",'Load Setting');
            if ~(isequal(File,0) || isequal(Path,0))
                FileName=[Path,File];
                F=fopen(FileName,'r');
                Settings=fread(F,inf,'*char')';
                fclose(F);
                app.ComPort.Value=app.ExtractSetting(Settings,'ComPort');
                app.BaudRate.Value=str2double(app.ExtractSetting(Settings,'BaudRate'));
                app.Echo.Value=str2double(app.ExtractSetting(Settings,'Echo'));
                app.RecivedType.Value=app.ExtractSetting(Settings,'RecivedType');
                app.SendTerminate.Value=str2double(app.ExtractSetting(Settings,'SendTerminate'));
                for i=1:app.PredefinesNumbers
                    Field=sprintf('PreDef%d',i);
                    app.(Field).Text=app.ExtractSetting(Settings,Field);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COMCONUIFigure = uifigure('Visible', 'off');
            app.COMCONUIFigure.Name = 'COMCON';
            app.COMCONUIFigure.CloseRequestFcn = createCallbackFcn(app, @COMCONUIFigureCloseRequest, true);
            app.COMCONUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.COMCONUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RefreshButton = uibutton(app.LeftPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ComDropDownLabel = uilabel(app.LeftPanel);
            app.ComDropDownLabel.Text = 'Com: ';
            app.ComPort = uidropdown(app.LeftPanel);
            app.ComPort.Items = {'COM3', 'COM8'};
            app.ComPort.Value = 'COM3';
            app.BaudRateEditFieldLabel = uilabel(app.LeftPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRate = uieditfield(app.LeftPanel, 'numeric');
            app.BaudRate.Value = 9600;
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.LeftPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.SaveSettingButton = uibutton(app.LeftPanel, 'push');
            app.SaveSettingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingButtonPushed, true);
            app.SaveSettingButton.Text = 'Save Setting';
            app.LoadSettingButton = uibutton(app.LeftPanel, 'push');
            app.LoadSettingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingButtonPushed, true);
            app.LoadSettingButton.Text = 'Load Setting';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Enable = 'off';
            app.RecivedTextArea = uitextarea(app.RightPanel);
            app.RecivedTextArea.Tag = 'ReciverBox';
            app.RecivedTextArea.Editable = 'off';
            app.RecivedTextArea.Enable = 'off';
            app.SendTextFiled = uieditfield(app.RightPanel, 'text');
            app.SendTextFiled.Enable = 'off';
            app.SendButton = uibutton(app.RightPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.SendTerminate = uicheckbox(app.RightPanel);
            app.SendTerminate.Tooltip = {'append \n as terminate'};
            app.SendTerminate.Text = 'CR';
            app.SendTerminate.Value = true;
            app.RecivedTypeDropDownLabel = uilabel(app.RightPanel);
            app.RecivedTypeDropDownLabel.Text = 'Recived Type :';
            app.RecivedType = uidropdown(app.RightPanel);
            app.RecivedType.Items = {'ASCII', 'HEX(8bit)'};
            app.RecivedType.Enable = 'off';
            app.RecivedType.Value = 'ASCII';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RecivedClearButton = uibutton(app.RightPanel, 'push');
            app.RecivedClearButton.ButtonPushedFcn = createCallbackFcn(app, @RecivedClearButtonPushed, true);
            app.RecivedClearButton.Text = 'Clear';
            app.SaveDef = uibutton(app.RightPanel, 'push');
            app.SaveDef.ButtonPushedFcn = createCallbackFcn(app, @SaveDefButtonPushed, true);
            app.SaveDef.Text = 'Save as predefine';
            app.PreDef1 = uibutton(app.RightPanel, 'push');
            app.PreDef1.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef1.Tag = 'PreDef1';
            app.PreDef1.Text = '';
            app.ClearSendText = uibutton(app.RightPanel, 'push');
            app.ClearSendText.ButtonPushedFcn = createCallbackFcn(app, @ClearSendTextButtonPushed, true);
            app.ClearSendText.Text = 'Clear';
            app.PreDef2 = uibutton(app.RightPanel, 'push');
            app.PreDef2.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef2.Tag = 'PreDef1';
            app.PreDef2.Text = '';
            app.PreDef3 = uibutton(app.RightPanel, 'push');
            app.PreDef3.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef3.Tag = 'PreDef1';
            app.PreDef3.Text = '';
            app.PreDef4 = uibutton(app.RightPanel, 'push');
            app.PreDef4.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef4.Tag = 'PreDef1';
            app.PreDef4.Text = '';
            app.PreDef5 = uibutton(app.RightPanel, 'push');
            app.PreDef5.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef5.Tag = 'PreDef1';
            app.PreDef5.Text = '';
            app.PreDef6 = uibutton(app.RightPanel, 'push');
            app.PreDef6.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef6.Tag = 'PreDef1';
            app.PreDef6.Text = '';
            app.PreDef7 = uibutton(app.RightPanel, 'push');
            app.PreDef7.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef7.Tag = 'PreDef1';
            app.PreDef7.Text = '';
            app.PreDef8 = uibutton(app.RightPanel, 'push');
            app.PreDef8.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef8.Tag = 'PreDef1';
            app.PreDef8.Text = '';
            app.PreDef9 = uibutton(app.RightPanel, 'push');
            app.PreDef9.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef9.Tag = 'PreDef1';
            app.PreDef9.Text = '';
            app.PreDef10 = uibutton(app.RightPanel, 'push');
            app.PreDef10.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef10.Tag = 'PreDef1';
            app.PreDef10.Text = '';
            app.PreDef11 = uibutton(app.RightPanel, 'push');
            app.PreDef11.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef11.Tag = 'PreDef1';
            app.PreDef11.Text = '';
            app.PreDef12 = uibutton(app.RightPanel, 'push');
            app.PreDef12.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef12.Tag = 'PreDef1';
            app.PreDef12.Text = '';
            app.PreDef13 = uibutton(app.RightPanel, 'push');
            app.PreDef13.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef13.Tag = 'PreDef1';
            app.PreDef13.Text = '';
            app.PreDef14 = uibutton(app.RightPanel, 'push');
            app.PreDef14.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef14.Tag = 'PreDef1';
            app.PreDef14.Text = '';
            app.PreDef15 = uibutton(app.RightPanel, 'push');
            app.PreDef15.ButtonPushedFcn = createCallbackFcn(app, @PreDefButtonsPushed, true);
            app.PreDef15.Tag = 'PreDef1';
            app.PreDef15.Text = '';
            app.Echo = uicheckbox(app.RightPanel);
            app.Echo.Text = 'Echo';
            app.Echo.Value = true;
            % Show the figure after all components are created
            app.COMCONUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComePortV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COMCONUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COMCONUIFigure)
        end
    end
end