classdef ComePortV1_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NGComPortUIFigure       matlab.ui.Figure
        PortSettingPanel        matlab.ui.container.Panel
        ComDropDownLabel        matlab.ui.control.Label
        ComPort                 matlab.ui.control.DropDown
        RefreshButton           matlab.ui.control.Button
        BaudRateEditFieldLabel  matlab.ui.control.Label
        BaudRate                matlab.ui.control.NumericEditField
        ConnectButton           matlab.ui.control.Button
        DisconnectButton        matlab.ui.control.Button
        SaveSettingButton       matlab.ui.control.Button
        LoadSettingButton       matlab.ui.control.Button
        CommunicationPanel      matlab.ui.container.Panel
        RecivedTypeLabel        matlab.ui.control.Label
        RecivedType             matlab.ui.control.DropDown
        SaveButton              matlab.ui.control.Button
        RecivedClearButton      matlab.ui.control.Button
        SendTextFiled           matlab.ui.control.EditField
        SaveDef                 matlab.ui.control.Button
        Echo                    matlab.ui.control.CheckBox
        PreDef1                 matlab.ui.control.Button
        PreDef2                 matlab.ui.control.Button
        PreDef3                 matlab.ui.control.Button
        PreDef4                 matlab.ui.control.Button
        PreDef5                 matlab.ui.control.Button
        PreDef6                 matlab.ui.control.Button
        PreDef7                 matlab.ui.control.Button
        PreDef8                 matlab.ui.control.Button
        PreDef9                 matlab.ui.control.Button
        PreDef10                matlab.ui.control.Button
        PreDef11                matlab.ui.control.Button
        PreDef12                matlab.ui.control.Button
        PreDef13                matlab.ui.control.Button
        PreDef14                matlab.ui.control.Button
        PreDef15                matlab.ui.control.Button
        RecivedTextArea         matlab.ui.control.TextArea
        PreDef16                matlab.ui.control.Button
        PreDef17                matlab.ui.control.Button
        PreDef18                matlab.ui.control.Button
        PreDef19                matlab.ui.control.Button
        PreDef20                matlab.ui.control.Button
        SendButton              matlab.ui.control.Button
        ClearSendText           matlab.ui.control.Button
        SendTerminate_1         matlab.ui.control.CheckBox
        SendTerminate_2         matlab.ui.control.CheckBox
        TLEffectDropDownLabel   matlab.ui.control.Label
        TLEffectDropDown        matlab.ui.control.DropDown
        LogPanel                matlab.ui.container.Panel
        LogPath                 matlab.ui.control.Button
        BrowseLogPath           matlab.ui.control.Button
        LogEnable               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        PredefinesNumbers=20 % Number of Predifined Buttoms
        SerialPort % Serial Port that connect to the device
        LogFileID=0 % The Log Flag
    end
    methods (Access = private)
        function ReciveData(app,SerialPort,~)
            Char=read(SerialPort,1,"char");
            if ~ismember(Char,[10,13])% \n =10 \r =13
                app.RecivedTextArea.Value{end}(end+1)=Char;
                if(app.LogFileID ~=0)
                    fprintf(app.LogFileID,'%c',Char);
                end
            elseif (Char == 10)
                if (rand < 0.1)
                    app.RecivedTextArea.Value(1:end-1)=[];
                end
                app.RecivedTextArea.Value{end+1}='';
                scroll(app.RecivedTextArea,'bottom');
                if(app.LogFileID ~=0)
                    fprintf(app.LogFileID,"\n");
                end
            elseif (Char == 13)
                if (app.TLEffectDropDown.Value=="New Line")
                    if (rand < 0.1)
                        app.RecivedTextArea.Value(1:end-1)=[];
                    end
                    app.RecivedTextArea.Value{end+1}='';
                    scroll(app.RecivedTextArea,'bottom');
                    if(app.LogFileID ~=0)
                        fprintf(app.LogFileID,"\n");
                    end
                end
            end
        end
        
        function Value = ExtractSetting(~,String,Patern)
            Patern=[Patern,':['];
            i=strfind(String,Patern);
            i=i+length(Patern);
            j=strfind(String(i:end),']');
            j=j(1)-1;
            Value=String(i:i+j-1);
        end
        
        function SendString(app,String,SendTerminate_1,SendTerminate_2)
            if isempty(String)
                return;
            end
            if app.Echo.Value
                app.RecivedTextArea.Value{end}(end+1:end+length(String))=String;
                if (SendTerminate_1 || SendTerminate_2)
                    app.RecivedTextArea.Value{end+1}='';
                    scroll(app.RecivedTextArea,'bottom');
                end
            end
            write(app.SerialPort,String,"string")
            if SendTerminate_1
                write(app.SerialPort,10,"char")
            end
            if SendTerminate_2
                write(app.SerialPort,13,"char")
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            isdeployed;
            app.ComPort.Items=serialportlist("all");
            app.ComPort.Value=app.ComPort.Items{end};
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.ComPort.Items=serialportlist("all");
        end

        % Button pushed function: SaveSettingButton
        function SaveSettingButtonPushed(app, event)
            [File,Path]=uiputfile("*.SCD",'Save Setting');
            if ~(isequal(File,0) || isequal(Path,0))
                FileName=[Path,File];
                F=fopen(FileName,'W');
                fprintf(F,'Comunication Setting File\nVersion202004\n\n\n\n\n\n\n');
                fprintf(F,'ComPort:[%s]\n',app.ComPort.Value);
                fprintf(F,'BaudRate:[%d]\n',app.BaudRate.Value);
                fprintf(F,'Echo:[%d]\n',app.Echo.Value);
                fprintf(F,'RecivedType:[%s]\n',app.RecivedType.Value);
                fprintf(F,'SendTerminate_1:[%d]\n',app.SendTerminate_1.Value);
                fprintf(F,'SendTerminate_2:[%d]\n',app.SendTerminate_2.Value);
                for i=1:app.PredefinesNumbers
                    Field=sprintf('PreDef%d',i);
                    fprintf(F,[Field,':[%s]\n'],app.(Field).Text);
                end
                fclose(F);
            end
        end

        % Button pushed function: LoadSettingButton
        function LoadSettingButtonPushed(app, event)
            [File,Path]=uigetfile("*.SCD",'Load Setting');
            if ~(isequal(File,0) || isequal(Path,0))
                FileName=[Path,File];
                F=fopen(FileName,'r');
                Settings=fread(F,inf,'*char')';
                fclose(F);
                app.ComPort.Value=app.ExtractSetting(Settings,'ComPort');
                app.BaudRate.Value=str2double(app.ExtractSetting(Settings,'BaudRate'));
                app.Echo.Value=str2double(app.ExtractSetting(Settings,'Echo'));
                app.RecivedType.Value=app.ExtractSetting(Settings,'RecivedType');
                app.SendTerminate_1.Value=str2double(app.ExtractSetting(Settings,'SendTerminate_1'));
                app.SendTerminate_2.Value=str2double(app.ExtractSetting(Settings,'SendTerminate_2'));
                for i=1:app.PredefinesNumbers
                    Field=sprintf('PreDef%d',i);
                    app.(Field).Text=app.ExtractSetting(Settings,Field);
                end
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            ConnectSucseesfully=true;
            try
                if isa(app.SerialPort,'handle')
                    app.SerialPort.delete;
                end
                app.SerialPort=serialport(app.ComPort.Value,app.BaudRate.Value);
                configureCallback(app.SerialPort,"byte",1,@app.ReciveData);
            catch Error
                ConnectSucseesfully=false;
                errordlg({'Unable To Connect',Error.message},...
                    'Connection Error');
            end
            if ConnectSucseesfully
                app.ComPort.Enable="off";
                app.BaudRate.Enable="off";
                app.RefreshButton.Enable="off";
                app.ConnectButton.Enable="off";
                configureTerminator(app.SerialPort,"CR");
                app.DisconnectButton.Enable="on";
                app.CommunicationPanel.Enable='on';
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.SerialPort.delete;
            app.ComPort.Enable="on";
            app.BaudRate.Enable="on";
            app.RefreshButton.Enable="on";
            app.ConnectButton.Enable="on";
            app.DisconnectButton.Enable="off";
            app.CommunicationPanel.Enable='off';
        end

        % Button pushed function: RecivedClearButton
        function RecivedClearButtonPushed(app, event)
            app.RecivedTextArea.Value={''};
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [File,Path]=uiputfile("*.txt",'Save Log');
            if ~(isequal(File,0) || isequal(Path,0))
                FileName=[Path,File];
                F=fopen(FileName,'W',"ieee-be",'UTF-8');
                for i=1:length(app.RecivedTextArea.Value)
                    fprintf(F,'%c',app.RecivedTextArea.Value{i});
                    fprintf(F,"\n");
                end
                fclose(F);
            end
        end

        % Button pushed function: PreDef1, PreDef10, PreDef11, 
        % PreDef12, PreDef13, PreDef14, PreDef15, PreDef16, 
        % PreDef17, PreDef18, PreDef19, PreDef2, PreDef20, PreDef3, 
        % PreDef4, PreDef5, PreDef6, PreDef7, PreDef8, PreDef9
        function PreDefsButtonPushed(app, event)
            app.SendString(event.Source.Text,0,0);
        end

        % Button pushed function: SaveDef
        function SaveDefButtonPushed(app, event)
            Predef=cell(1,app.PredefinesNumbers);
            List=cell(1,app.PredefinesNumbers);
            for i=1:app.PredefinesNumbers
                Predef{i}=sprintf('PreDef%d',i);
                if isempty(app.(Predef{i}).Text)
                    Text='[empty]';
                else
                    Text=app.(Predef{i}).Text;
                    while ismember(Text(end),[10,13])
                        Text(end)=[];
                    end
                end
                List{i}=['Def ',num2str(i,'%02d: '),Text];
            end
            [Selected,UserSelec] = listdlg('PromptString','Select a Predefine index that You want to save on it.',...
                'SelectionMode','single','listsize',[160 220],'ListString',List);
            if UserSelec
                if ~isempty(app.(Predef{Selected}).Text)
                    Ans = questdlg("Replace it?",'',"Yes","No","No");
                    if (Ans~="Yes")
                        return;
                    end
                end
                app.(Predef{Selected}).Text=app.SendTextFiled.Value;
                if app.SendTerminate_1.Value
                    app.(Predef{Selected}).Text(end+1)=10;
                end
                if app.SendTerminate_2.Value
                    app.(Predef{Selected}).Text(end+1)=13;
                end
            end
        end

        % Button pushed function: ClearSendText
        function ClearSendTextButtonPushed(app, event)
            app.SendTextFiled.Value='';
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            app.SendString(app.SendTextFiled.Value,app.SendTerminate_1.Value,app.SendTerminate_2.Value);
        end

        % Close request function: NGComPortUIFigure
        function NGComPortUIFigureCloseRequest(app, event)
            if isa(app.SerialPort,'handle')
                app.SerialPort.delete;
            end
            delete(app)
        end

        % Value changed function: LogEnable
        function LogEnableValueChanged(app, event)
            value = app.LogEnable.Value;
            if value==0
                app.BrowseLogPath.Enable=false;
                app.LogPath.Enable=false;
                if (app.LogFileID~=0)
                    fclose(app.LogFileID);
                end
                app.LogFileID=0;
            else
                app.BrowseLogPath.Enable=true;
                app.LogPath.Enable=true;
                app.LogPath.Text='';
                app.LogFileID=0;
            end
        end

        % Button pushed function: BrowseLogPath
        function BrowseLogPathButtonPushed(app, event)
            [File,Path]=uiputfile("*.txt",'Log File');
            if ~(isequal(File,0) || isequal(Path,0))
                app.LogPath.Text=[Path,File];
                app.LogFileID=fopen([Path,File],'W',"ieee-be",'UTF-8');
            else
                if (app.LogFileID~=0)
                    fclose(app.LogFileID);
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NGComPortUIFigure = uifigure('Visible', 'off');
            app.NGComPortUIFigure.Name = 'NGComPort';
            app.NGComPortUIFigure.Icon = 'Icon.png';
            app.NGComPortUIFigure.CloseRequestFcn = createCallbackFcn(app, @NGComPortUIFigureCloseRequest, true);
            app.PortSettingPanel = uipanel(app.NGComPortUIFigure);
            app.PortSettingPanel.Title = 'Port Setting';
            app.ComDropDownLabel = uilabel(app.PortSettingPanel);
            app.ComDropDownLabel.Text = 'Com: ';
            app.ComPort = uidropdown(app.PortSettingPanel);
            app.ComPort.Items = {'COM3', 'COM8'};
            app.ComPort.Value = 'COM3';
            app.RefreshButton = uibutton(app.PortSettingPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.BaudRateEditFieldLabel = uilabel(app.PortSettingPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRate = uieditfield(app.PortSettingPanel, 'numeric');
            app.BaudRate.Value = 9600;
            app.ConnectButton = uibutton(app.PortSettingPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.PortSettingPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.SaveSettingButton = uibutton(app.PortSettingPanel, 'push');
            app.SaveSettingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingButtonPushed, true);
            app.SaveSettingButton.Text = 'Save Setting';
            app.LoadSettingButton = uibutton(app.PortSettingPanel, 'push');
            app.LoadSettingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingButtonPushed, true);
            app.LoadSettingButton.Text = 'Load Setting';
            app.CommunicationPanel = uipanel(app.NGComPortUIFigure);
            app.CommunicationPanel.Enable = 'off';
            app.RecivedTypeLabel = uilabel(app.CommunicationPanel);
            app.RecivedTypeLabel.Text = 'Recived Type :';
            app.RecivedType = uidropdown(app.CommunicationPanel);
            app.RecivedType.Items = {'ASCII', 'HEX(8bit)'};
            app.RecivedType.Enable = 'off';
            app.RecivedType.Value = 'ASCII';
            app.SaveButton = uibutton(app.CommunicationPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RecivedClearButton = uibutton(app.CommunicationPanel, 'push');
            app.RecivedClearButton.ButtonPushedFcn = createCallbackFcn(app, @RecivedClearButtonPushed, true);
            app.RecivedClearButton.Text = 'Clear';
            app.SendTextFiled = uieditfield(app.CommunicationPanel, 'text');
            app.SaveDef = uibutton(app.CommunicationPanel, 'push');
            app.SaveDef.ButtonPushedFcn = createCallbackFcn(app, @SaveDefButtonPushed, true);
            app.SaveDef.Text = 'Save as predefine';
            app.Echo = uicheckbox(app.CommunicationPanel);
            app.Echo.Text = 'Echo';
            app.Echo.Value = true;
            app.PreDef1 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef1.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef1.Tag = 'PreDef1';
            app.PreDef1.Text = '';
            app.PreDef2 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef2.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef2.Tag = 'PreDef1';
            app.PreDef2.Text = '';
            app.PreDef3 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef3.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef3.Tag = 'PreDef1';
            app.PreDef3.Text = '';
            app.PreDef4 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef4.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef4.Tag = 'PreDef1';
            app.PreDef4.Text = '';
            app.PreDef5 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef5.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef5.Tag = 'PreDef1';
            app.PreDef5.Text = '';
            app.PreDef6 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef6.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef6.Tag = 'PreDef1';
            app.PreDef6.Text = '';
            app.PreDef7 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef7.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef7.Tag = 'PreDef1';
            app.PreDef7.Text = '';
            app.PreDef8 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef8.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef8.Tag = 'PreDef1';
            app.PreDef8.Text = '';
            app.PreDef9 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef9.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef9.Tag = 'PreDef1';
            app.PreDef9.Text = '';
            app.PreDef10 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef10.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef10.Tag = 'PreDef1';
            app.PreDef10.Text = '';
            app.PreDef11 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef11.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef11.Tag = 'PreDef1';
            app.PreDef11.Text = '';
            app.PreDef12 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef12.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef12.Tag = 'PreDef1';
            app.PreDef12.Text = '';
            app.PreDef13 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef13.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef13.Tag = 'PreDef1';
            app.PreDef13.Text = '';
            app.PreDef14 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef14.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef14.Tag = 'PreDef1';
            app.PreDef14.Text = '';
            app.PreDef15 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef15.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef15.Tag = 'PreDef1';
            app.PreDef15.Text = '';
            app.RecivedTextArea = uitextarea(app.CommunicationPanel);
            app.PreDef16 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef16.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef16.Tag = 'PreDef1';
            app.PreDef16.Text = '';
            app.PreDef17 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef17.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef17.Tag = 'PreDef1';
            app.PreDef17.Text = '';
            app.PreDef18 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef18.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef18.Tag = 'PreDef1';
            app.PreDef18.Text = '';
            app.PreDef19 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef19.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef19.Tag = 'PreDef1';
            app.PreDef19.Text = '';
            app.PreDef20 = uibutton(app.CommunicationPanel, 'push');
            app.PreDef20.ButtonPushedFcn = createCallbackFcn(app, @PreDefsButtonPushed, true);
            app.PreDef20.Tag = 'PreDef1';
            app.PreDef20.Text = '';
            app.SendButton = uibutton(app.CommunicationPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ClearSendText = uibutton(app.CommunicationPanel, 'push');
            app.ClearSendText.ButtonPushedFcn = createCallbackFcn(app, @ClearSendTextButtonPushed, true);
            app.ClearSendText.Text = 'Clear';
            app.SendTerminate_1 = uicheckbox(app.CommunicationPanel);
            app.SendTerminate_1.Tooltip = {'append \n as terminate'};
            app.SendTerminate_1.Text = 'CR';
            app.SendTerminate_1.Value = true;
            app.SendTerminate_2 = uicheckbox(app.CommunicationPanel);
            app.SendTerminate_2.Tooltip = {'append \n as terminate'};
            app.SendTerminate_2.Text = 'TL';
            app.SendTerminate_2.Value = true;
            app.TLEffectDropDownLabel = uilabel(app.CommunicationPanel);
            app.TLEffectDropDownLabel.Text = 'TL Effect:';
            app.TLEffectDropDown = uidropdown(app.CommunicationPanel);
            app.TLEffectDropDown.Items = {'New Line', 'Ignore'};
            app.TLEffectDropDown.Value = 'New Line';
            app.LogPanel = uipanel(app.NGComPortUIFigure);
            app.LogPanel.Title = 'Log :';
            app.LogPath = uibutton(app.LogPanel, 'push');
            app.LogPath.Enable = 'off';
            app.LogPath.Text = '';
            app.BrowseLogPath = uibutton(app.LogPanel, 'push');
            app.BrowseLogPath.ButtonPushedFcn = createCallbackFcn(app, @BrowseLogPathButtonPushed, true);
            app.BrowseLogPath.Enable = 'off';
            app.BrowseLogPath.Text = 'Browse';
            app.LogEnable = uicheckbox(app.LogPanel);
            app.LogEnable.ValueChangedFcn = createCallbackFcn(app, @LogEnableValueChanged, true);
            app.LogEnable.Text = 'Log';
            % Show the figure after all components are created
            app.NGComPortUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ComePortV1_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NGComPortUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NGComPortUIFigure)
        end
    end
end